classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel  matlab.ui.container.Panel
        BrowseFilePanel            matlab.ui.container.Panel
        LocationEditFieldLabel     matlab.ui.control.Label
        LocationEditField          matlab.ui.control.EditField
        OpenButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        SelectFilterDropDownLabel  matlab.ui.control.Label
        SelectFilterDropDown       matlab.ui.control.DropDown
        PlayOriginalButton         matlab.ui.control.Button
        PlayFilteredButton         matlab.ui.control.Button
        AmplitudeSliderLabel       matlab.ui.control.Label
        AmplitudeSlider            matlab.ui.control.Slider
        FrequencyHzSliderLabel     matlab.ui.control.Label
        FrequencyHzSlider          matlab.ui.control.Slider
        StatusLabel                matlab.ui.control.Label
        ParameteraEditFieldLabel   matlab.ui.control.Label
        ParameteraEditField        matlab.ui.control.EditField
        ParameterbEditFieldLabel   matlab.ui.control.Label
        ParameterbEditField        matlab.ui.control.EditField
        SelectLabel                matlab.ui.control.Label
        SelectDropDown             matlab.ui.control.DropDown
    end

    
    methods (Access = public)
        
        function drawOriginalW(app,x,Fs)
            % Plot the waveform.
                tDouble = (1 : length(x)) / Fs; % Get time axis as a double.  Convert number of elements into actual seconds (still a double though).
                tDouble = tDouble / 60; % Convert to minutes (still a double though).
                tMin = datetime(0,0,0) + minutes(tDouble); % Convert variable class from "double" to "duration".
                plot(app.UIAxes,tMin, x(:, 1));  % Display waveform.
        end
        function drawFilteredW(app,y,Fs)
            % Plot the filtered waveform.
                tDouble = (1 : length(y)) / Fs; % Get time axis as a double.  Convert number of elements into actual seconds (still a double though).
                tDouble = tDouble / 60; % Convert to minutes (still a double though).
                tMin = datetime(0,0,0) + minutes(tDouble); % Convert variable class from "double" to "duration".
                plot(app.UIAxes2,tMin, y(:, 1));  % Display waveform.
        end
        function drawMag(app,b,a)
               [h,w]=freqz(b,a);
               if(app.SelectDropDown.Value=="w")
                    [h,f]=freqz(b,a);
                    dB = mag2db(abs(h));
                    plot(app.UIAxes4,2*pi*f,dB)
                    xlabel(app.UIAxes4,'2 \pi f')
               else
                    dB = mag2db(abs(h));
                    plot(app.UIAxes4,w/2*pi,dB)
                    xlabel(app.UIAxes4,'\omega / 2 \pi')
              end     
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.FrequencyHzSlider.Enable=false;
                app.FrequencyHzSliderLabel.Enable=false;
                app.AmplitudeSlider.Enable=false;
                app.AmplitudeSliderLabel.Enable=false;
        end

        % Value changed function: FrequencyHzSlider
        function FrequencyHzSliderValueChanged(app, event)
            value = app.FrequencyHzSlider.Value;
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [file,path] = uigetfile('*.wav');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.LocationEditField.Value = fullfile(path,file);
            end
        end

        % Value changed function: LocationEditField
        function LocationEditFieldValueChanged(app, event)
            value = app.LocationEditField.Value;
            
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            if isfile(app.LocationEditField.Value)
                [x,Fs] = audioread(app.LocationEditField.Value);
                clear sound;% stop other sounds
                sound(x, Fs, 16);
                drawOriginalW(app,x,Fs);
                
            else
                warndlg('Please select a wav file','Warning');
            end
            
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear sound;% stop other sounds
            delete(app)
            
        end

        % Button pushed function: PlayFilteredButton
        function PlayFilteredButtonPushed(app, event)
            if isfile(app.LocationEditField.Value)
                if(isnan(app.ParameteraEditField.Value))%a degeri bos ise
                    a=0;
                else
                    a=str2double(strsplit(app.ParameteraEditField.Value));
                end
                value = app.SelectFilterDropDown.Value;
                [x,Fs] = audioread(app.LocationEditField.Value);
                
                    if(value=="Static Filter")
                        
                        if(isnan(app.ParameteraEditField.Value))%a degeri bos ise
                            a=0;
                        else
                            a=str2double(strsplit(app.ParameteraEditField.Value));
                        end
                       
                        if(isempty(app.ParameterbEditField.Value))%b degeri bos ise
                            
                            app.StatusLabel.Text="FIR";
                            b=1;
                        else
                            b=str2double(strsplit(app.ParameterbEditField.Value));
                            flag=isstable(b,a);%kararlılık durumu
                        
                            if(flag)
                                app.StatusLabel.Text="IIR stable";
                            else
                                app.StatusLabel.Text="IIR unstable";
                            end
                        end
                        y = filter(b,a,x);
                        clear sound;% stop other sounds
                        sound(y, Fs, 16);
                        drawFilteredW(app,y,Fs);
                        
                        %for Magnitude Response !!
                        drawMag(app,b,a);
                        
                        [h,f]=freqz(b,a);
                        dB = mag2db(abs(h));
                        if(dB(1)==dB(length(dB)))
                            app.StatusLabel.Text=[app.StatusLabel.Text "Band Pass Filter"];
                        elseif(dB(1)<dB(length(dB)))
                            app.StatusLabel.Text=[app.StatusLabel.Text "High Pass Filter"];
                        elseif(dB(1)>dB(length(dB)))
                            app.StatusLabel.Text=[app.StatusLabel.Text "Low Pass Filter"];
                        end
                        %for pole-zero 
                        zplane(b,a,app.UIAxes3);  
                    else
                        %Allpass filter
                        clear sound;% stop other sounds
                        A=app.AmplitudeSlider.Value;
                        w=app.FrequencyHzSlider.Value;
                        y=[];
                        for n = 1:length(x)
                             y=[y a*x(n)*(A*cos(w*n))];
                        end
                        clear sound;% stop other sounds
                        sound(y, Fs, 16);
                        drawFilteredW(app,y.',Fs)
                        app.StatusLabel.Text="IIR";
                        %for Magnitude Response !!
                        drawMag(app,y(1),a);
                        %for pole-zero 
                        zplane(y(1),a,app.UIAxes3);  
                    end
                % Plot the original waveform.
                drawOriginalW(app,x,Fs);


            else
                warndlg('Please select a wav file','Warning');
            end
                
        end

        % Value changed function: SelectFilterDropDown
        function SelectFilterDropDownValueChanged(app, event)
            value = app.SelectFilterDropDown.Value;
            if(value=="Static Filter")
                app.ParameterbEditField.Enable=true;
                app.FrequencyHzSlider.Enable=false;
                app.FrequencyHzSliderLabel.Enable=false;
                app.AmplitudeSlider.Enable=false;
                app.AmplitudeSliderLabel.Enable=false;
            else
                app.ParameterbEditField.Enable=false;
                app.FrequencyHzSlider.Enable=true;
                app.FrequencyHzSliderLabel.Enable=true;
                app.AmplitudeSlider.Enable=true;
                app.AmplitudeSliderLabel.Enable=true;
            end
        end

        % Value changed function: ParameteraEditField
        function ParameteraEditFieldValueChanged(app, event)
            value = app.ParameteraEditField.Value;
        end

        % Value changed function: ParameterbEditField
        function ParameterbEditFieldValueChanged(app, event)
            value = app.ParameterbEditField.Value;
        end

        % Value changed function: SelectDropDown
        function SelectDropDownValueChanged(app, event)
            value = app.SelectDropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel = uipanel(app.UIFigure);
            app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel.Title = 'CME4462 DIGITAL AUDIO PROCESSING TERM ASSIGNMENT';
            app.BrowseFilePanel = uipanel(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.BrowseFilePanel.Title = 'Browse File';
            app.LocationEditFieldLabel = uilabel(app.BrowseFilePanel);
            app.LocationEditFieldLabel.Text = 'Location:';
            app.LocationEditField = uieditfield(app.BrowseFilePanel, 'text');
            app.LocationEditField.ValueChangedFcn = createCallbackFcn(app, @LocationEditFieldValueChanged, true);
            app.OpenButton = uibutton(app.BrowseFilePanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.UIAxes = uiaxes(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            title(app.UIAxes, 'Original Waveform')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            title(app.UIAxes2, 'Filtered Waveform')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            title(app.UIAxes3, 'Pole-zero')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            title(app.UIAxes4, 'Magnitude Response')
            xlabel(app.UIAxes4, '2 \pi f')
            ylabel(app.UIAxes4, 'Magnitude(dB)')
            app.SelectFilterDropDownLabel = uilabel(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.SelectFilterDropDownLabel.Text = 'Select Filter:';
            app.SelectFilterDropDown = uidropdown(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.SelectFilterDropDown.Items = {'Static Filter', 'Dynamic Filter'};
            app.SelectFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFilterDropDownValueChanged, true);
            app.SelectFilterDropDown.Value = 'Static Filter';
            app.PlayOriginalButton = uibutton(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayFilteredButton = uibutton(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel, 'push');
            app.PlayFilteredButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredButtonPushed, true);
            app.PlayFilteredButton.Text = 'Play Filtered';
            app.AmplitudeSliderLabel = uilabel(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.FrequencyHzSliderLabel = uilabel(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.FrequencyHzSliderLabel.Text = 'Frequency(Hz)';
            app.FrequencyHzSlider = uislider(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.FrequencyHzSlider.Limits = [0 10];
            app.FrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzSliderValueChanged, true);
            app.StatusLabel = uilabel(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.StatusLabel.Text = 'Status';
            app.ParameteraEditFieldLabel = uilabel(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.ParameteraEditFieldLabel.Text = 'Parameter a:';
            app.ParameteraEditField = uieditfield(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel, 'text');
            app.ParameteraEditField.ValueChangedFcn = createCallbackFcn(app, @ParameteraEditFieldValueChanged, true);
            app.ParameterbEditFieldLabel = uilabel(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.ParameterbEditFieldLabel.Text = 'Parameter b:';
            app.ParameterbEditField = uieditfield(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel, 'text');
            app.ParameterbEditField.ValueChangedFcn = createCallbackFcn(app, @ParameterbEditFieldValueChanged, true);
            app.SelectLabel = uilabel(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.SelectLabel.Text = 'Select:';
            app.SelectDropDown = uidropdown(app.CME4462DIGITALAUDIOPROCESSINGTERMASSIGNMENTPanel);
            app.SelectDropDown.Items = {'w', 'f'};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDropDownValueChanged, true);
            app.SelectDropDown.Value = 'w';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end